classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimesteptrhSlider               matlab.ui.control.Slider
        TimesteptrhSliderLabel          matlab.ui.control.Label
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        PollutantconcentrationC0mgkgEditField  matlab.ui.control.NumericEditField
        PollutantconcentrationC0mgkgEditFieldLabel  matlab.ui.control.Label
        PermeabilitycoefficientkscmhEditField  matlab.ui.control.NumericEditField
        PermeabilitycoefficientkscmhEditFieldLabel  matlab.ui.control.Label
        AveragesuctioncmEditField       matlab.ui.control.NumericEditField
        AveragesuctioncmEditFieldLabel  matlab.ui.control.Label
        InitialwatercontentiEditField   matlab.ui.control.NumericEditField
        InitialwatercontentiEditFieldLabel  matlab.ui.control.Label
        PorosityEditField               matlab.ui.control.NumericEditField
        PorosityLabel                   matlab.ui.control.Label
        MaterialpropertiesLabel         matlab.ui.control.Label
        StationDropDown                 matlab.ui.control.DropDown
        StationDropDownLabel            matlab.ui.control.Label
        PollutantDropDown               matlab.ui.control.DropDown
        PollutantDropDownLabel          matlab.ui.control.Label
        DepositheightHmSlider           matlab.ui.control.Slider
        DurationButtonGroup             matlab.ui.container.ButtonGroup
        MonthhighestpeakButton          matlab.ui.control.RadioButton
        MonthmostvolumeButton           matlab.ui.control.RadioButton
        Year2024Button                  matlab.ui.control.RadioButton
        DepositheightHmSliderLabel      matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    H double = 20
    tr double = 1
    C0 double = 200
    rho double = 2670
    p double = 1
    station string = "Airolo"
    theta_s double = 0.155
    K_s double = 0.7
    Psi double = 11.6
    theta_i double = 0.056
    timescale string = "Year 2024"
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
GreenAmpt_output = GreenAmpt(app.station, app.timescale, app.K_s, app.Psi, app.theta_i, app.theta_s);

[Caq_n_filtered,time_filtered, app.H, app.p, app.tr, app.station, app.timescale] = Diffusion(app.H,app.p,app.tr,app.station,app.timescale,app.rho,GreenAmpt_output,app.C0);


% Pollutant-specific labels
if app.p == 1
    pollutant_name = 'Nitrate';
    color = [0.1 0.5 0.8];  % bluish
elseif app.p == 2
    pollutant_name = 'Arsenic';
    color = [0.8 0.2 0.2];  % reddish
end

% Plot leachate concentration
% Clear existing content from UIAxes
cla(app.UIAxes);
hold(app.UIAxes, 'on')

% Scatter plot
scatter(app.UIAxes, time_filtered, Caq_n_filtered, 40, ...
    'filled', 'MarkerFaceColor', color, 'MarkerEdgeColor', 'k');

% Line plot
plot(app.UIAxes, time_filtered, Caq_n_filtered, '-', ...
    'LineWidth', 2, 'Color', color);

% Labels and title
xlabel(app.UIAxes, 'Time [hours]', 'Interpreter', 'latex', 'FontSize', 14);
ylabel(app.UIAxes, '$C_{\mathrm{aq}}$ [mg/L]', 'Interpreter', 'latex', 'FontSize', 14);
title(app.UIAxes, [pollutant_name ' concentration in Leachate'], ...
    'Interpreter', 'latex', 'FontSize', 16);

% Axis settings
grid(app.UIAxes, 'on');
box(app.UIAxes, 'on');
set(app.UIAxes, 'FontSize', 12, 'LineWidth', 1.2);
xlim(app.UIAxes, [min(time_filtered) max(time_filtered)]);
ylim(app.UIAxes, [0 max(Caq_n_filtered)*1.1]);


        end

        % Value changed function: PollutantDropDown
        function PollutantDropDownValueChanged(app, event)
            pollutant_name= app.PollutantDropDown.Value;
            
            if pollutant_name == "Nitrate (NO3)"
                app.p=1;
            elseif pollutant_name =="Arsenic (As)"
                app.p=2;
            end

        end

        % Value changed function: DepositheightHmSlider
        function DepositheightHmSliderValueChanged(app, event)
            app.H = app.DepositheightHmSlider.Value;
            
            
        end

        % Value changed function: StationDropDown
        function StationDropDownValueChanged(app, event)
            app.station = app.StationDropDown.Value;
           

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
if app.p == 1
    pollutant_name = 'Nitrate';
elseif app.p == 2
    pollutant_name = 'Arsenic';
end

% Axes labels and title
xlabel(app.UIAxes, 'Time [hours]', 'Interpreter', 'latex', 'FontSize', 14)
ylabel(app.UIAxes, '$C_{\mathrm{aq}}$ [mg/L]', 'Interpreter', 'latex', 'FontSize', 14)
title(app.UIAxes, [pollutant_name,' Concentration in Leachate'], ...
    'Interpreter', 'latex', 'FontSize', 16)

% Grid, axes properties
grid(app.UIAxes, 'on')
box(app.UIAxes, 'on')
app.UIAxes.FontSize = 12;
app.UIAxes.LineWidth = 1.2;

        end

        % Value changed function: PorosityEditField
        function PorosityEditFieldValueChanged(app, event)
         value = app.PorosityEditField.Value;

    % Check if the value is numeric, between 0 and 1, and >= theta_i
    if isnumeric(value) && isscalar(value) && value >= app.theta_i && value <= 1
        app.theta_s = value;
    else
        % If invalid, show a warning dialog and reset the value
        uialert(app.UIFigure, ...
            sprintf('Porosity must be a number between %.3f and 1.', app.theta_i), ...
            'Invalid Input');
        % Reset the value to previous or default valid one
        app.PorosityEditField.Value = app.theta_s; 
    end


        end

        % Value changed function: InitialwatercontentiEditField
        function InitialwatercontentiEditFieldValueChanged(app, event)
            value = app.InitialwatercontentiEditField.Value;
            

             % Check if the value is numeric, between 0 and 1, and lower
             % than the porosity
    if isnumeric(value) && isscalar(value) && value >=0 && value <= 1 && value <= app.theta_s
        app.theta_i = value;
    else
        % If invalid, show a warning dialog and reset the value
        uialert(app.UIFigure, ...
            sprintf('Initial water content must be a number between 0 and 1, and lower than %.3f.', app.theta_s), ...
            'Invalid Input');
        % Reset the value to previous or default valid one
        app.InitialwatercontentiEditField.Value = app.theta_i; 
    end
        end

        % Value changed function: AveragesuctioncmEditField
        function AveragesuctioncmEditFieldValueChanged(app, event)
            app.Psi = app.AveragesuctioncmEditField.Value;
            
        end

        % Value changed function: PermeabilitycoefficientkscmhEditField
        function PermeabilitycoefficientkscmhEditFieldValueChanged(app, event)
            app.K_s = app.PermeabilitycoefficientkscmhEditField.Value;
            
        end

        % Value changed function: Densitykgm3EditField
        function Densitykgm3EditFieldValueChanged(app, event)
            app.rho = app.Densitykgm3EditField.Value;
            
        end

        % Value changed function: PollutantconcentrationC0mgkgEditField
        function PollutantconcentrationC0mgkgEditFieldValueChanged(app, event)
            app.C0 = app.PollutantconcentrationC0mgkgEditField.Value;
            
        end

        % Selection changed function: DurationButtonGroup
        function DurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.DurationButtonGroup.SelectedObject;
            

            switch selectedButton
                case app.MonthhighestpeakButton
                    app.timescale = "June 2024";
                case app.MonthmostvolumeButton
                    app.timescale = "March 2024";
                case app.Year2024Button
                    app.timescale = "Year 2024";
            end

        end

        % Value changed function: TimesteptrhSlider
        function TimesteptrhSliderValueChanged(app, event)
            app.tr = app.TimesteptrhSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration in leachate')
            xlabel(app.UIAxes, 'Time [hours]')
            ylabel(app.UIAxes, 'Aqueous concentration [mg/L]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DepositheightHmSliderLabel = uilabel(app.UIFigure);
            app.DepositheightHmSliderLabel.Text = 'Deposit height (H [m])';
            app.DurationButtonGroup = uibuttongroup(app.UIFigure);
            app.DurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DurationButtonGroupSelectionChanged, true);
            app.DurationButtonGroup.Title = 'Duration';
            app.Year2024Button = uiradiobutton(app.DurationButtonGroup);
            app.Year2024Button.Text = 'Year (2024)';
            app.Year2024Button.Value = true;
            app.MonthmostvolumeButton = uiradiobutton(app.DurationButtonGroup);
            app.MonthmostvolumeButton.Text = 'Month (most volume)';
            app.MonthhighestpeakButton = uiradiobutton(app.DurationButtonGroup);
            app.MonthhighestpeakButton.Text = 'Month (highest peak)';
            app.DepositheightHmSlider = uislider(app.UIFigure);
            app.DepositheightHmSlider.Limits = [2 30];
            app.DepositheightHmSlider.ValueChangedFcn = createCallbackFcn(app, @DepositheightHmSliderValueChanged, true);
            app.DepositheightHmSlider.Value = 2;
            app.PollutantDropDownLabel = uilabel(app.UIFigure);
            app.PollutantDropDownLabel.Text = 'Pollutant';
            app.PollutantDropDown = uidropdown(app.UIFigure);
            app.PollutantDropDown.Items = {'Nitrate (NO3)', 'Arsenic (As)'};
            app.PollutantDropDown.ValueChangedFcn = createCallbackFcn(app, @PollutantDropDownValueChanged, true);
            app.PollutantDropDown.Value = 'Nitrate (NO3)';
            app.StationDropDownLabel = uilabel(app.UIFigure);
            app.StationDropDownLabel.Text = 'Station';
            app.StationDropDown = uidropdown(app.UIFigure);
            app.StationDropDown.Items = {'Biasca', 'Airolo'};
            app.StationDropDown.ValueChangedFcn = createCallbackFcn(app, @StationDropDownValueChanged, true);
            app.StationDropDown.Value = 'Biasca';
            app.MaterialpropertiesLabel = uilabel(app.UIFigure);
            app.MaterialpropertiesLabel.Text = 'Material properties';
            app.PorosityLabel = uilabel(app.UIFigure);
            app.PorosityLabel.Text = 'Porosity (η [-])';
            app.PorosityEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorosityEditField.ValueChangedFcn = createCallbackFcn(app, @PorosityEditFieldValueChanged, true);
            app.InitialwatercontentiEditFieldLabel = uilabel(app.UIFigure);
            app.InitialwatercontentiEditFieldLabel.Text = 'Initial water content (θi [-])';
            app.InitialwatercontentiEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialwatercontentiEditField.ValueChangedFcn = createCallbackFcn(app, @InitialwatercontentiEditFieldValueChanged, true);
            app.AveragesuctioncmEditFieldLabel = uilabel(app.UIFigure);
            app.AveragesuctioncmEditFieldLabel.Text = 'Average suction (Ψ [cm])';
            app.AveragesuctioncmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragesuctioncmEditField.ValueChangedFcn = createCallbackFcn(app, @AveragesuctioncmEditFieldValueChanged, true);
            app.PermeabilitycoefficientkscmhEditFieldLabel = uilabel(app.UIFigure);
            app.PermeabilitycoefficientkscmhEditFieldLabel.Text = 'Permeability coefficient (ks [cm/h])';
            app.PermeabilitycoefficientkscmhEditField = uieditfield(app.UIFigure, 'numeric');
            app.PermeabilitycoefficientkscmhEditField.ValueChangedFcn = createCallbackFcn(app, @PermeabilitycoefficientkscmhEditFieldValueChanged, true);
            app.PollutantconcentrationC0mgkgEditFieldLabel = uilabel(app.UIFigure);
            app.PollutantconcentrationC0mgkgEditFieldLabel.Text = 'Pollutant concentration (C0 [mg/kg])';
            app.PollutantconcentrationC0mgkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PollutantconcentrationC0mgkgEditField.ValueChangedFcn = createCallbackFcn(app, @PollutantconcentrationC0mgkgEditFieldValueChanged, true);
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density (ρ [kg/m3) ';
            app.Densitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densitykgm3EditFieldValueChanged, true);
            app.TimesteptrhSliderLabel = uilabel(app.UIFigure);
            app.TimesteptrhSliderLabel.Text = 'Timestep (tr [h])';
            app.TimesteptrhSlider = uislider(app.UIFigure);
            app.TimesteptrhSlider.Limits = [1 24];
            app.TimesteptrhSlider.ValueChangedFcn = createCallbackFcn(app, @TimesteptrhSliderValueChanged, true);
            app.TimesteptrhSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end